classdef MyGyroGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        StartButton            matlab.ui.control.Button
        ResetpositionButton    matlab.ui.control.Button
        COMsDropDownLabel      matlab.ui.control.Label
        COMsDropDown           matlab.ui.control.DropDown
        LOGPanel               matlab.ui.container.Panel
        GyrooverloadLampLabel  matlab.ui.control.Label
        GyrooverloadLamp       matlab.ui.control.Lamp
        AccoverloadLampLabel   matlab.ui.control.Label
        AccoverloadLamp        matlab.ui.control.Lamp
        LogTextArea            matlab.ui.control.TextArea
        AccEditFieldLabel      matlab.ui.control.Label
        AccEditField           matlab.ui.control.EditField
        GyroEditFieldLabel     matlab.ui.control.Label
        GyroEditField          matlab.ui.control.EditField
        UIAxes                 matlab.ui.control.UIAxes
    end

   
    properties (Access = private)
        %timers
        generic_upd_tmr = timer;
        %data
        port_list;
        connection_log = {'Connected'};
        sent_log;
        rsvd_log;
        %vars
        current_port = ' ';
        stateOnOff = 0;
        
        IMU
        basis=[1 0 0; 0 1 0; 0 0 1];
        coordinate=zeros(3,1);
        overload = 0;
        cnt = 0;
        prewBas=[];
        prewVec=[];        
    end
    
    methods (Access = private)

        function form_upd(app)
            if (app.stateOnOff == 0)
                app.COMsDropDown.Items = seriallist;
            else%if (app.IMU.NumBytesAvailable >= 9*4)
                    stop(app.generic_upd_tmr);
                    
                    [app.basis, app.overload]=app.getIMUdata(app.IMU);
                    
                    delete(app.prewBas);
                    app.prewBas = app.drawBasis(app.coordinate, app.basis, 0.05);                    
                
                    if (bitand(app.overload, 0x04)  == 4)
                        app.AccoverloadLamp.Color = 'red';
                        %disp('overload acc');
                    else
                        app.AccoverloadLamp.Color = 'white';
                    end
                    if (bitand(app.overload, 0x02) == 2)
                        %disp('overload gyro');
                        app.GyrooverloadLamp.Color = 'red';
                    else
                        app.GyrooverloadLamp.Color = 'white';
                    end
                    start(app.generic_upd_tmr);
             end       
        end
        
        function res = drawBasis(app, coord, bas, scale)
               res = quiver3(app.UIAxes,coord(1),coord(2),coord(3), bas(1,1),bas(2,1),bas(3,1), scale,'r');
               res = [res,quiver3(app.UIAxes,coord(1),coord(2),coord(3), bas(1,2),bas(2,2),bas(3,2), scale,'g')];
               res = [res,quiver3(app.UIAxes,coord(1),coord(2),coord(3), bas(1,3),bas(2,3),bas(3,3), scale,'b')];
        end        

        function [bas, overload]=getIMUdata(app, port)
            s=read(port,10,'int8');
            
            bas(1,1) = s(1)/100;
            bas(1,2) = s(2)/100;
            bas(1,3) = s(3)/100;
            bas(2,1) = s(4)/100;
            bas(2,2) = s(5)/100;
            bas(2,3) = s(6)/100;
            bas(3,1) = s(7)/100;
            bas(3,2) = s(8)/100;
            bas(3,3) = s(9)/100;
            overload = s(10);
        end         
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.drawBasis([0 0 0],app.basis, 0.1);
        plot3(app.UIAxes,0,0,0,'ob');
                %create gui upd timer
        app.generic_upd_tmr.period = 0.02;
        app.generic_upd_tmr.ExecutionMode = 'fixedRate';
        app.generic_upd_tmr.timerFcn = @(~, ~)app.form_upd();
        
        %update form
        start(app.generic_upd_tmr);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if (app.stateOnOff == 0) 
                stop(app.generic_upd_tmr);
                app.StartButton.Text = "Stop";
                app.IMU = serialport(app.COMsDropDown.Value,115200,"Timeout",15);
                app.COMsDropDown.Enable = 'off';
                app.stateOnOff = 1;
                start(app.generic_upd_tmr);
            else
                app.stateOnOff = 0;
                delete(app.IMU);
                app.COMsDropDown.Enable = 'on';
                app.StartButton.Text = "Start";
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.generic_upd_tmr);
            delete(app.generic_upd_tmr)
            delete(app.IMU)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetpositionButton = uibutton(app.Panel, 'push');
            app.ResetpositionButton.Text = 'Reset position';
            app.COMsDropDownLabel = uilabel(app.Panel);
            app.COMsDropDownLabel.Text = 'COMs';
            app.COMsDropDown = uidropdown(app.Panel);
            app.LOGPanel = uipanel(app.UIFigure);
            app.LOGPanel.Title = 'LOG';
            app.GyrooverloadLampLabel = uilabel(app.LOGPanel);
            app.GyrooverloadLampLabel.Text = 'Gyro overload';
            app.GyrooverloadLamp = uilamp(app.LOGPanel);
            app.AccoverloadLampLabel = uilabel(app.LOGPanel);
            app.AccoverloadLampLabel.Text = 'Acc overload';
            app.AccoverloadLamp = uilamp(app.LOGPanel);
            app.LogTextArea = uitextarea(app.LOGPanel);
            app.LogTextArea.Editable = 'off';
            app.AccEditFieldLabel = uilabel(app.LOGPanel);
            app.AccEditFieldLabel.Text = 'Acc';
            app.AccEditField = uieditfield(app.LOGPanel, 'text');
            app.AccEditField.Editable = 'off';
            app.GyroEditFieldLabel = uilabel(app.LOGPanel);
            app.GyroEditFieldLabel.Text = 'Gyro';
            app.GyroEditField = uieditfield(app.LOGPanel, 'text');
            app.GyroEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-0.125 0.125];
            app.UIAxes.YLim = [-0.125 0.125];
            app.UIAxes.ZLim = [-0.125 0.125];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyGyroGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end